classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        sEditField                 matlab.ui.control.NumericEditField
        sEditFieldLabel            matlab.ui.control.Label
        mEditField                 matlab.ui.control.NumericEditField
        mEditFieldLabel            matlab.ui.control.Label
        ycEditField                matlab.ui.control.NumericEditField
        ycEditFieldLabel           matlab.ui.control.Label
        xcEditField                matlab.ui.control.NumericEditField
        xcEditFieldLabel           matlab.ui.control.Label
        rEditField                 matlab.ui.control.NumericEditField
        rEditFieldLabel            matlab.ui.control.Label
        TipuldefigurDropDown       matlab.ui.control.DropDown
        TipuldefigurDropDownLabel  matlab.ui.control.Label
        dEditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel            matlab.ui.control.Label
        cEditField                 matlab.ui.control.NumericEditField
        cEditFieldLabel            matlab.ui.control.Label
        bEditField                 matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        aEditField                 matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        XEditField_6               matlab.ui.control.NumericEditField
        XEditField_5               matlab.ui.control.NumericEditField
        XEditField_4               matlab.ui.control.NumericEditField
        XEditField_3               matlab.ui.control.NumericEditField
        XEditField_2               matlab.ui.control.NumericEditField
        XEditField_1               matlab.ui.control.NumericEditField
        XLabel                     matlab.ui.control.Label
        PEditField_6               matlab.ui.control.NumericEditField
        PEditField_5               matlab.ui.control.NumericEditField
        PEditField_4               matlab.ui.control.NumericEditField
        PEditField_3               matlab.ui.control.NumericEditField
        PEditField_2               matlab.ui.control.NumericEditField
        PEditField_1               matlab.ui.control.NumericEditField
        PLabel                     matlab.ui.control.Label
        ERRORLabel                 matlab.ui.control.Label
        betaEditField              matlab.ui.control.NumericEditField
        betaEditFieldLabel         matlab.ui.control.Label
        alphaEditField             matlab.ui.control.NumericEditField
        alphaEditFieldLabel        matlab.ui.control.Label
        thetaEditField             matlab.ui.control.NumericEditField
        thetaEditFieldLabel        matlab.ui.control.Label
        EnterButton                matlab.ui.control.Button
        NumruldevalorigenerateEditField  matlab.ui.control.NumericEditField
        NumruldevalorigenerateEditFieldLabel  matlab.ui.control.Label
        pEditField                 matlab.ui.control.NumericEditField
        pEditFieldLabel            matlab.ui.control.Label
        nEditField                 matlab.ui.control.NumericEditField
        nEditFieldLabel            matlab.ui.control.Label
        DistribuiileListBox        matlab.ui.control.ListBox
        DistribuiileListBoxLabel   matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        mediaEditField             matlab.ui.control.NumericEditField
        dispersiaEditField         matlab.ui.control.NumericEditField
        infoText                   matlab.ui.control.Label
        PLOTLabel                  matlab.ui.control.Label
        INFOLabel                  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        
        function h = Histograma(app, x)
%           fig = uifigure;
%           ax = uiaxes(fig);
            hist = app.UIAxes;
            cla(hist);
            view(hist, 2);
            h = histogram(hist, x);
            axis(hist, "normal");
            %h.BinWidth = 0.05;
            h.BinMethod = "auto";
        end


        % sim. var. Bernoulli
        function y = SimBernoulli(~, p)
	        u = rand;
            if u < p 
                y = 1;
            else 
                y = 0;
            end
        end

        function Bernoulli(app)
            p = app.pEditField.Value;
            nvar = app.NumruldevalorigenerateEditField.Value;
            x=zeros(1,nvar);

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);

            app.mediaEditField.Visible = "on";
            app.dispersiaEditField.Visible = "on";
            app.mediaEditField.Position = [300,881,51,22];
            app.dispersiaEditField.Position = [300,850,51,22];
            app.mediaEditField.Value = p;
            app.dispersiaEditField.Value = p*(1-p);

            for i=1 : nvar
                x(i) = SimBernoulli(app, p);
            end

            xlabel(app.UIAxes, "Valori generate");
            ylabel(app.UIAxes, "Numar de valori");
            hist = Histograma(app, x);
            
%           disp('Variabilele generate: ');
%           x
        end


        % sim. var. binomiale
        function x = SimBinom(~, p, n)
            u = rand(1,n);
            x = sum(u<p);
        end

        function Binomial(app)
            p = app.pEditField.Value;
            n = app.nEditField.Value;
            nvar = app.NumruldevalorigenerateEditField.Value;
            x=zeros(1,nvar);

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);

            app.mediaEditField.Position = [300,885,51,22];
            app.dispersiaEditField.Position = [300,854,51,22];
            app.mediaEditField.Visible = "on";
            app.dispersiaEditField.Visible = "on";
            app.mediaEditField.Value = n * p;
            app.dispersiaEditField.Value = n * p * (1-p);
            
            for i=1 : nvar
                x(i) = SimBinom(app, p, n);
            end
            
            xlabel(app.UIAxes, "Valori generate");
            ylabel(app.UIAxes, "Numar de valori");
            hist = Histograma(app, x);
        end


        % simularea var. geom. (2)
        function x = SimGeom2(~, p)
            u = rand;
	        x = floor(log(1-u)/log(1-p)) + 1;
        end

        function Geometric(app)
            p = app.pEditField.Value;
            nvar = app.NumruldevalorigenerateEditField.Value;
            x=zeros(1,nvar);

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);

            app.mediaEditField.Position = [300,881,51,22];
            app.dispersiaEditField.Position = [300,850,51,22];
            app.mediaEditField.Visible = "on";
            app.dispersiaEditField.Visible = "on";
            app.mediaEditField.Value = 1/p;
            app.dispersiaEditField.Value = (1-p)/(p^2);

            for i=1 : nvar
                x(i) = SimGeom2(app, p);
            end
            
            xlabel(app.UIAxes, "Valori generate");
            ylabel(app.UIAxes, "Numar de valori");
            hist = Histograma(app, x);
            %hist.BinWidth = 0.05;
        end


        % simularea var. uniforme (continue) pe [a,b)
        function x = SimUnif(app, a,b)
            x = [];
        
	        u = rand;
            x = a + (b-a)*u;
            app.ERRORLabel.Visible='off';
        end

        function y = DensitateUnif(~, x, a, b)
            if and(x >= a, x < b)
                y = 1./(b-a);
            else
                y = 0;
            end
        end

        function y = RepartitieUnif(~, x, a, b)
            if x < a
                y = 0;
            elseif and(x >= a, x < b)
                y = (x - a)./(b - a);
            else
                y = 1;
            end
        end

        function PlotDensitateUnif(app, a, b)
            densit = app.UIAxes_2;

            x_dom_1 = a-100 : 0.05 : a-0.5;
            x_dom_2 = a : 0.05 : b-0.5;
            x_dom_3 = b+0.5 : 0.05 : b+100;
            y_1=zeros(1, length(x_dom_1));
            for i = 1 : length(x_dom_1)
                y_1(i) = DensitateUnif(app, x_dom_1(i), a, b);
            end
            y_2=zeros(1, length(x_dom_2));
            for i = 1 : length(x_dom_2)
                y_2(i) = DensitateUnif(app, x_dom_2(i), a, b);
            end
            y_3=zeros(1, length(x_dom_3));
            for i = 1 : length(x_dom_3)
                y_3(i) = DensitateUnif(app, x_dom_3(i), a, b);
            end
            
            plot(densit, x_dom_1, y_1,'r', x_dom_2, y_2,'r', x_dom_3, y_3,'r', LineWidth=2);
            hold on;
            axis(densit, "padded");
            
            grid(densit, "on");
            hold off;
            close all;
        end

        function PlotRepartitieUnif(app, a, b)
            repart = app.UIAxes_3;

            x_dom_1 = a-100 : 0.05 : a;
            x_dom_2 = a : 0.05 : b;
            x_dom_3 = b : 0.05 : b+100;
            y_1=zeros(1,length(x_dom_1));
            for i = 1 : length(x_dom_1)
                y_1(i) = RepartitieUnif(app, x_dom_1(i), a, b);
            end
            y_2=zeros(1,length(x_dom_2));
            for i = 1 : length(x_dom_2)
                y_2(i) = RepartitieUnif(app, x_dom_2(i), a, b);
            end
            y_3=zeros(1,length(x_dom_3));
            for i = 1 : length(x_dom_3)
                y_3(i) = RepartitieUnif(app, x_dom_3(i), a, b);
            end
                

%           plot(repart, x_dom_1, y_1,'b', x_dom_2, y_2,'b', x_dom_3, y_3,'b', LineWidth=2);

            x_merged = [x_dom_1 x_dom_2 x_dom_3];
            y_merged = [y_1 y_2 y_3];

            hold on;
            axis(repart, "padded");
            
            grid(repart, "on");
            hold off;
            close all;

            my_comet(repart, x_merged, y_merged);
            
        end

        function Uniform(app)
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            nvar = app.NumruldevalorigenerateEditField.Value;
            x=zeros(1,nvar);

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);

            app.mediaEditField.Position = [300,890,51,22];
            app.dispersiaEditField.Position = [300,852,51,22];
            app.mediaEditField.Visible = "on";
            app.dispersiaEditField.Visible = "on";
            app.mediaEditField.Value = (a+b)/2;
            app.dispersiaEditField.Value = (a-b)^12/2;

            if a < b
                for i=1 : nvar
                    x(i) = SimUnif(app, a, b);
                end
                
                xlabel(app.UIAxes, "Valori generate");
                ylabel(app.UIAxes, "Numar de valori");
                hist = Histograma(app, x);
                PlotDensitateUnif(app, a, b);
                PlotRepartitieUnif(app, a, b);
            else
                % verificarea conditiilor
                app.ERRORLabel.Text="ERROR: Nu se respecta conditia a < b";
                app.ERRORLabel.Visible='on';
                %fprintf("Eroare ! Nu se respecta conditia a < b !\n");
                return;
            end    
        end


        % sim. var. neuniforme (discrete)
        function xk = SimDiscretN(app, x, p, n)	% x, p = tablouri
        
            xk = [];
            
	        k = 0;
	        F = 0;
	        u = rand;
        
            while u >= F
                k = k + 1;
                F = F + p(k);
            end
            
            xk = x(k);
        end

        function Non_uniform(app)
            p(1) = app.PEditField_1.Value;
            p(2) = app.PEditField_2.Value;
            p(3) = app.PEditField_3.Value;
            p(4) = app.PEditField_4.Value;
            p(5) = app.PEditField_5.Value;
            p(6) = app.PEditField_6.Value;
            x(1) = app.XEditField_1.Value;
            x(2) = app.XEditField_2.Value;
            x(3) = app.XEditField_3.Value;
            x(4) = app.XEditField_4.Value;
            x(5) = app.XEditField_5.Value;
            x(6) = app.XEditField_6.Value;
            nvar = app.NumruldevalorigenerateEditField.Value;
            n = 6;

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);


            flag = 1;
            for i = 1 : numel(x)-1
                    if x(i) >= x(i+1)
                        app.ERRORLabel.Text="ERROR: Nu se respecta ordinea crescatoare a valorilor lui x!";
                        app.ERRORLabel.Visible='on';
                        %fprintf("Eroare! Nu se respecta ordinea crescatoare a valorilor lui x !\n");
                        flag = 0;
                        break;
                    end
            end

            % verificarea conditiilor
            double(sum(p))
            if double(sum(p)) ~= double(1)
                app.ERRORLabel.Text="ERROR: Suma probabilitatilor nu este 1!";
                app.ERRORLabel.Visible='on';
                %fprintf("Eroare! Suma probabilitatilor nu este 1 !\n");
                flag = 0;
            end
            
            
            if flag == 1
                app.ERRORLabel.Visible='off';
                z=zeros(1,nvar);
                for i=1 : nvar
                    z(i) = SimDiscretN(app, x, p, n);
                end
            
                xlabel(app.UIAxes, "Valori generate");
                ylabel(app.UIAxes, "Numar de valori");
                hist = Histograma(app, z);
            end
        end


        % simularea var. exponentiale
        function x = SimExp(~, theta)
	        u = rand;
	        x = -theta * log(1-u);
        end

        function y = DensitateExp(~, x, theta)
            if x < 0
                y = 0;
            elseif x >= 0
                y = (1./theta) .* exp(-x./theta); 
            end
        end
    
        function y = RepartitieExp(~, x, theta)
            if x < 0
                y = 0;
            elseif x >= 0
                y = 1 - exp(-x./theta);
            end
        end
            
        function PlotDensitateExp(app, theta)  
            densit = app.UIAxes_2;
                
            x_dom_1 = -100 : 0.05 : 0-0.05;
            x_dom_2 = 0 : 0.05 : 100;
            y_1=zeros(1,length(x_dom_1));
            for i = 1 : length(x_dom_1)
                y_1(i) = DensitateExp(app, x_dom_1(i), theta);
            end
            y_2=zeros(1,length(x_dom_2));
            for i = 1 : length(x_dom_2)
                y_2(i) = DensitateExp(app, x_dom_2(i), theta);
            end

            plot(densit, x_dom_1, y_1,'r', x_dom_2, y_2,'r', LineWidth=2);
            hold on;
            axis(densit, "padded");
            grid(densit, "on");
            hold off;
            close all;
        end

        function PlotRepartitieExp(app, theta)
            repart = app.UIAxes_3;
               
            x_dom_1 = -100 : 0.05 : 0-0.05;
            x_dom_2 = 0 : 0.05 : 100;
            y_1=zeros(1,length(x_dom_1));
            for i = 1 : length(x_dom_1)
                y_1(i) = RepartitieExp(app, x_dom_1(i), theta);
            end
            y_2=zeros(1,length(x_dom_2));
            for i = 1 : length(x_dom_2)
                y_2(i) = RepartitieExp(app, x_dom_2(i), theta);
            end

%             plot(repart, x_dom_1, y_1,'b', x_dom_2, y_2,'b', LineWidth=2);

            x_merged = [x_dom_1 x_dom_2];
            y_merged = [y_1 y_2];

            hold on;
            axis(repart, "padded");
            grid(repart, "on");
            hold off;
            close all;

            my_comet(repart, x_merged, y_merged);
            
   
        end

        function Exponential(app)
            theta = app.thetaEditField.Value;
            nvar = app.NumruldevalorigenerateEditField.Value;
            x=zeros(1,nvar);

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);
            
            app.mediaEditField.Position = [300,926,51,22];
            app.dispersiaEditField.Position = [300,895,51,22];
            app.mediaEditField.Visible = "on";
            app.dispersiaEditField.Visible = "on";
            app.mediaEditField.Value = theta;
            app.dispersiaEditField.Value = theta^2;

            for i=1 : nvar
                x(i) = SimExp(app, theta);
            end
            
            xlabel(app.UIAxes, "Valori generate");
            ylabel(app.UIAxes, "Numar de valori");
            hist = Histograma(app, x);
            PlotDensitateExp(app, theta);
            PlotRepartitieExp(app, theta);
%           disp('Variabilele generate: ')
%           x
        end


        function y = SimPareto(~, alpha, beta)
            u = rand;
            y = beta / ((1-u)^(1/alpha));
        end
            
        function y = DensitatePareto(~, x, alpha, beta)
            if x < beta
                y = 0;
            elseif x >= beta
                y = (alpha./beta).*(beta./x).^(alpha+1);
            end
        end

        function y = RepartitiePareto(~, x, alpha, beta)
            if x < beta
                y = 0;
            elseif x >= beta
                y = 1 - (beta./x).^alpha;
            end
        end

        function PlotDensitatePareto(app, alpha, beta)
            densit = app.UIAxes_2;

            x_dom_1 = beta - 100 : 0.05 : beta - 0.05;
            x_dom_2 = beta : 0.05 : beta + 100;

            y_1 = DensitatePareto(app, x_dom_1, alpha, beta);
            y_2 = DensitatePareto(app, x_dom_2, alpha, beta);
            
            plot(densit, x_dom_1,y_1,'r', x_dom_2,y_2,'r', 'LineWidth',2);
            hold on;
            axis(densit, "padded");
            grid(densit, "on");
            hold off;
            close all;
        end

        function PlotRepartitiePareto(app, alpha, beta)
            repart = app.UIAxes_3;

            x_dom_1 = beta - 100 : 0.05 : beta - 0.05;
            x_dom_2 = beta : 0.05 : beta + 100;

            y_1 = RepartitiePareto(app, x_dom_1, alpha, beta);
            y_2 = RepartitiePareto(app, x_dom_2, alpha, beta);

            plot(repart, x_dom_1,y_1,'b', x_dom_2,y_2,'b', 'LineWidth',2);
            hold on;
            grid(repart,"on");
            axis(repart, "padded");
            hold off;
            close all;
        end

        function Pareto(app)
            alpha = app.alphaEditField.Value;
            beta = app.betaEditField.Value;
            nvar = app.NumruldevalorigenerateEditField.Value;

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);

            app.mediaEditField.Position = [350,924,51,22];
            app.dispersiaEditField.Position = [350,886,51,22];
            app.mediaEditField.Visible = "on";
            app.dispersiaEditField.Visible = "on";
            

            if alpha > 1 & beta > 0 | alpha > 2 & beta > 0
                x=zeros(1,nvar);
                for i=1 : nvar
                    x(i) = SimPareto(app, alpha, beta);
                end
                app.dispersiaEditField.Value = alpha*beta^2/(((alpha-1)^2)*(alpha-2));
                app.mediaEditField.Value = alpha*beta/(alpha-1);
                xlabel(app.UIAxes, "Valori generate");
                ylabel(app.UIAxes, "Numar de valori");
                hist = Histograma(app, x);
                PlotDensitatePareto(app, alpha, beta);
                PlotRepartitiePareto(app, alpha, beta);
    %           disp('Variabilele generate: ')
    %           x
            end
           
            if ~(alpha > 1 & beta > 0)
                app.ERRORLabel.Text="ERROR: Nu se respecta conditia alpha > 1, beta > 0";
                app.ERRORLabel.Visible='on';
            elseif ~(alpha > 2 & beta > 0)
                app.ERRORLabel.Text="ERROR: Nu se respecta conditia alpha > 2, beta > 0";
                app.ERRORLabel.Visible='on';
            else
                app.ERRORLabel.Visible='off';
            end


        end
        

        % simularea unui vector aleator uniform
        function p = SimRect(app, a, b, c, d)
            p = [];
            p(1) = a + (b-a)*rand;
            p(2) = c + (d-c)*rand;
        end

        function p = SimCircle(app, xc, yc, r)
            p = [];
            a = xc - r;
            b = xc + r;
            c = yc - r;
            d = yc + r;

            while 1
                p(1) = a + (b - a)*rand;
                p(2) = c + (d - c)*rand;

                if ((p(1) - xc)^2 + (p(2)- yc)^2 <= r^2)
                    break;
                end
            end
        end

        function p = SimEllipse(app, xc, yc, a, b)
            p = [];
            a_dom = xc - a;
            b_dom = xc + a;
            c_dom = yc - b;
            d_dom = yc + b;

            while 1
                p(1) = a_dom + (b_dom - a_dom)*rand;
                p(2) = c_dom + (d_dom - c_dom)*rand;

                if (p(1) - xc)^2/a^2 + (p(2) - yc)^2/b^2 <= 1
                    break;
                end
            end
        end

        
        function PlotRectangle(app, a, b, c ,d, p)
            plot_rect = app.UIAxes;

            x = p(:, 1);
            y = p(:, 2);

            plot(plot_rect, x, y, "x r");
            hold on;
            rectangle(plot_rect, 'Position', [a c b-a d-c], 'EdgeColor','k', 'LineWidth',1);
            grid (plot_rect, "on");
            axis(plot_rect, "padded");
            axis(plot_rect, "equal");
            hold off;
            close all;
        end

        function PlotCircle(app, xc, yc, r, p)
            plot_circle = app.UIAxes;

            t = 0 : pi/100 : 2*pi;
            x_dom = xc + r * cos(t);
            y_dom = yc + r * sin(t);

            x = p(:, 1);
            y = p(:, 2);
    
           
            plot(plot_circle, x, y, "x r", x_dom, y_dom, "b");
            hold on;
            grid (plot_circle, "on");
            axis(plot_circle, "padded");
            axis(plot_circle, "equal");
            hold off;
            close all;
        end

        function PlotEllipse(app, xc, yc, a, b, p)
            plot_ellipse = app.UIAxes;

            t = 0 : pi/100 : 2*pi;
            x_dom = xc + a*cos(t);
            y_dom = yc + b*sin(t);

            x = p(:, 1);
            y = p(:, 2);

            plot(plot_ellipse, x, y, "x r", x_dom, y_dom, "b");
            hold on;
            grid (plot_ellipse, "on");
            axis(plot_ellipse, "padded");
            axis(plot_ellipse, "equal");
            hold off;
            close all;
        end

        
        function z = DensitateRect(app, a, b, c, d, x, y)
            A = (b-a).*(d-c);
           
            if x >= a & x <= b & y >= c & y <= d
                z = 1./A;
            else
                z = 0;
            end
        end

        function z = DensitateCircle(app, r, xc, yc, x, y)
            A = pi * r^2;
           
            if (x - xc)^2 + (y- yc)^2 <= r^2
                z = 1./A;
            else
                z = 0;
            end
        end

        function z = DensitateEllipse(app, a, b, xc, yc, x, y)
            A = pi * a * b;
           
            if ((x - xc)^2)/a^2 + ((y- yc)^2)/b^2 <= 1
                z = 1./A;
            else
                z = 0;
            end
        end
        
        function PlotDensitateRect(app, a, b, c, d)
            densit = app.UIAxes_2;

            x_dom = a - 2 : 0.05 : b + 2;
            y_dom = c - 2 : 0.05 : d + 2;

            [X Y] = meshgrid(x_dom, y_dom);

            [rows cols] = size(X);


            Z = zeros(rows, cols);
        
            for i = 1 : numel(X)
                Z(i) = DensitateRect(app, a, b, c, d, X(i), Y(i));
            end

            
            surf(densit, X, Y, Z);
            colormap (densit, "parula");
            shading(densit, "interp");
        end

        function PlotDensitateCircle(app, r, xc, yc)
            densit = app.UIAxes_2;

            x_dom = xc - r - 2 : 0.05 : xc + r + 2;
            y_dom = yc - r - 2 : 0.05 : yc + r + 2;

            [X Y] = meshgrid(x_dom, y_dom);

            [rows cols] = size(X);


            Z = zeros(rows, cols);
        
            for i = 1 : numel(X)
                Z(i) = DensitateCircle(app, r, xc, yc, X(i), Y(i));
            end

            
            surf(densit, X, Y, Z);
            colormap (densit, "parula");
            shading(densit, "interp");
        end
        
        function PlotDensitateEllipse(app, a, b, xc, yc)
            densit = app.UIAxes_2;

            x_dom = xc -a - 2 : 0.05 : xc + a + 2;
            y_dom = yc -b - 2 : 0.05 : yc + b + 2;

            [X Y] = meshgrid(x_dom, y_dom);

            [rows cols] = size(X);

            Z = zeros(rows, cols);
        
            for i = 1 : numel(X)
                Z(i) = DensitateEllipse(app, a, b, xc, yc, X(i), Y(i));
            end

            
            surf(densit, X, Y, Z);
            colormap (densit, "parula");
            shading(densit, "interp");
        end


        function RandomUniformVector(app)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);

            xlabel(app.UIAxes, "X");
            ylabel(app.UIAxes, "Y");

            nvar = app.NumruldevalorigenerateEditField.Value;
            tipfigura = app.TipuldefigurDropDown.Value;

            p=zeros(nvar, 2);

            switch tipfigura
                case "Dreptunghi"
                    a = app.aEditField.Value;
                    b = app.bEditField.Value;
                    c = app.cEditField.Value;
                    d = app.dEditField.Value;

                    if a <= b & c <= d
                        for i=1 : nvar
                            p(i,:) = SimRect(app, a, b, c, d);
                        end
                        PlotRectangle(app, a, b, c, d, p);
                        PlotDensitateRect(app, a, b, c, d);
                    else
                        % verificarea conditiilor
                        if a >= b
                            app.ERRORLabel.Text="ERROR: Nu se respecta conditia a < b";
                            app.ERRORLabel.Visible='on';
                            %fprintf("Eroare ! Nu se respecta conditia a < b !\n");
                            return;
                        end
                        if c >= d
                            app.ERRORLabel.Text="ERROR: Nu se respecta conditia c < d";
                            app.ERRORLabel.Visible='on';
                            return;
                        end
                        app.ERRORLabel.Visible='off';
                    end
                case "Cerc"
                    xc = app.xcEditField.Value;
                    yc = app.ycEditField.Value;
                    r = app.rEditField.Value;
                    for i=1 : nvar
                        p(i,:) = SimCircle(app, xc, yc, r);
                    end
                    PlotCircle(app, xc, yc, r, p);
                    PlotDensitateCircle(app, r, xc, yc);      
                case "Elipsa"
                    xc = app.xcEditField.Value;
                    yc = app.ycEditField.Value;
                    a = app.aEditField.Value;
                    b = app.bEditField.Value;
                    for i=1 : nvar
                        p(i,:) = SimEllipse(app, xc, yc, a, b);
                    end
                    PlotEllipse(app, xc, yc, a, b, p);
                    PlotDensitateEllipse(app, a, b, xc, yc);
            end


        end
        function x = SimNormal(~,m, sigma)
            x = normrnd(m, sigma);
        end

        function Normal(app)
            m = app.mEditField.Value;
            sigma = app.sEditField.Value;
            nvar = app.NumruldevalorigenerateEditField.Value;

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            view(app.UIAxes, 2);
            view(app.UIAxes_2, 2);
            view(app.UIAxes_3, 2);

            app.mediaEditField.Visible = "off";
            app.dispersiaEditField.Visible = "off";
            
            x=zeros(1,nvar);
            for i=1 : nvar
                x(i) = SimNormal(app, m, sigma);
            end
            
            xlabel(app.UIAxes, "Valori generate");
            ylabel(app.UIAxes, "Numar de valori");
            hist = Histograma(app, x);

        end
   end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            value = app.DistribuiileListBox.Value;
            switch value
                case 'Bernoulli'
                    app.UIAxes.Title.String = "Histograma";
                    app.UIAxes_2.Title.String = "*Lipseste*";
                    app.UIAxes_3.Title.String = "*Lipseste*";
%                     app.UIAxes.XLabel = "Valori generate";
%                     app.UIAxes.YLabel = "Numarul valorilor";
                    Bernoulli(app);
                case 'Geometric'
                    app.UIAxes.Title.String = "Histograma";
                    app.UIAxes_2.Title.String = "*Lipseste*";
                    app.UIAxes_3.Title.String = "*Lipseste*";
%                     app.UIAxes.XLabel = "Valori generate";
%                     app.UIAxes.YLabel = "Numarul valorilor";
                    Geometric(app);
                case 'Binomial'
                    app.UIAxes.Title.String = "Histograma";
                    app.UIAxes_2.Title.String = "*Lipseste*";
                    app.UIAxes_3.Title.String = "*Lipseste*";
%                     app.UIAxes.XLabel = "Valori generate";
%                     app.UIAxes.YLabel = "Numarul valorilor";
                    Binomial(app);
                case 'Uniform'
                    app.UIAxes.Title.String = "Histograma";
                    app.UIAxes_2.Title.String = "Densitate de probabilitate";
                    app.UIAxes_3.Title.String = "Functia de repartitie";
%                     app.UIAxes.XLabel = "Valori generate";
%                     app.UIAxes.YLabel = "Numarul valorilor";
                    Uniform(app);
                case 'Non-uniform'
                    app.UIAxes.Title.String = "Histograma";
                    app.UIAxes_2.Title.String = "*Lipseste*";
                    app.UIAxes_3.Title.String = "*Lipseste*";
%                     app.UIAxes.XLabel = "Valori generate";
%                     app.UIAxes.YLabel = "Numarul valorilor";
                    Non_uniform(app);
                case 'Exponential'
                    app.UIAxes.Title.String = "Histograma";
                    app.UIAxes_2.Title.String = "Densitate de probabilitate";
                    app.UIAxes_3.Title.String = "Functia de repartitie";
%                     app.UIAxes.XLabel = "Valori generate";
%                     app.UIAxes.YLabel = "Numarul valorilor";
                    Exponential(app);
                case 'Pareto'
                    app.UIAxes.Title.String = "Histograma";
                    app.UIAxes_2.Title.String = "Densitate de probabilitate";
                    app.UIAxes_3.Title.String = "Functia de repartitie";
%                     app.      UIAxes.XLabel = "Valori generate";
%                     app.UIAxes.YLabel = "Numarul valorilor";
                    Pareto(app);
                case 'Random uniform vector'
                    app.UIAxes.Title.String = "Harta punctelor generate";
                    app.UIAxes_2.Title.String = "Densitate de probabilitate";
                    app.UIAxes_3.Title.String = "*Lipseste*";
%                     app.UIAxes.XLabel = "X";
%                     app.UIAxes.YLabel = "Y";
                    RandomUniformVector(app);
                case 'Normal'
                    app.UIAxes.Title.String = "Histograma";
                    app.UIAxes_2.Title.String = "*Lipseste*";
                    app.UIAxes_3.Title.String = "*Lipseste*";
                    Normal(app);
                otherwise
                    uialert(uifigure, ...
                        'Optiune nedefinita ! Alegeti o optiune valida !', ...
                        'Mesaj Eroare'...
                    );
                    
                    
            end
        end

        % Value changed function: DistribuiileListBox
        function DistribuiileListBoxValueChanged(app, event)
            value = app.DistribuiileListBox.Value;
            switch value
                case 'Bernoulli'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "<div ><div>O variabila aleatoare " + ...
                        "discreta Bernoulli cuantifica rezultatul unui " + ...
                        "experiment aleator (Bernoulli) format dintr-un " + ...
                        "număr cunoscut sau necunoscut de incercari " + ...
                        "independente, fiecare avand doar doua posibilitati: " + ...
                        "</div><div style='margin: 1000px 0 0 0;'><ul " + ...
                        "style='margin-top: 10px'><li>Succes - 1</li><li>" + ...
                        "Esec - 0</li></ul></div></div>" + ...
                        "<br>&emsp;&emsp;&emsp;‣ Media:&emsp;&emsp;&emsp;&emsp;  " + ...
                        "M(X) = p" + ...
                        "<br><br>&emsp;&emsp;&emsp;‣ Dispersia:&emsp;&emsp;&emsp;" + ...
                        "&sigma;<sup>2</sup>(X) = " + ...
                        "p * q";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.xcEditFieldLabel.Visible = "off";
                    app.xcEditField.Visible = "off";
                    app.ycEditFieldLabel.Visible = "off";
                    app.ycEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "off";
                    app.TipuldefigurDropDownLabel.Visible = "off";
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.pEditField.Visible = 'on';
                    app.pEditFieldLabel.Visible = 'on';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                    app.mEditField.Visible='off';
                    app.mEditFieldLabel.Visible='off';
                    app.sEditField.Visible='off';
                    app.sEditFieldLabel.Visible='off';
                   
                case 'Binomial'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "O variabila aleatoare distribuita " + ...
                        "binomial, cuantifica numarul de succese din " + ...
                        "<i>n</i> incercari " + ...
                        "asociate unui eveniment Bernoulli." + ...
                        "<br><br><br><br><br>&emsp;&emsp;&emsp;‣ Media:&emsp;&emsp;&emsp;  &emsp;" + ...
                        "M(X) = n * p" + ...
                        "<br><br>&emsp;&emsp;&emsp;‣ Dispersia:" + ...
                        "&emsp;&emsp;&emsp;&sigma;<sup>2</sup>(X) = n * p * q";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "off";
                    app.TipuldefigurDropDownLabel.Visible = "off";
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.xcEditFieldLabel.Visible = "off";
                    app.xcEditField.Visible = "off";
                    app.ycEditFieldLabel.Visible = "off";
                    app.ycEditField.Visible = "off";
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.nEditField.Visible='on';
                    app.nEditFieldLabel.Visible='on';
                    app.aEditField.Visible = 'off';
                    app.pEditFieldLabel.Visible = 'on';
                    app.pEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                    app.mEditField.Visible='off';
                    app.mEditFieldLabel.Visible='off';
                    app.sEditField.Visible='off';
                    app.sEditFieldLabel.Visible='off';
                case 'Geometric'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "O variabila aleatoare distribuita " + ...
                        "geometric, cuantifica numarul de incercari pana la " + ...
                        "primul succes <b>(inclusiv)</b>.<br><br>" + ...
                        "<br><br><br>&emsp;&emsp;&emsp;‣ Media:&emsp;&emsp;&emsp;&emsp;  " + ...
                        "M(X) = <sup>1</sup>&frasl;<sub>p</sub>" + ...
                        "<br><br>&emsp;&emsp;&emsp;‣ Dispersia:&emsp;&emsp;&emsp;" + ...
                        "&sigma;<sup>2</sup>(X)= " + ...
                        "<sup>q</sup>&frasl;<sub>p<sup>2</sup></sub>";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "off";
                    app.TipuldefigurDropDownLabel.Visible = "off";
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.xcEditFieldLabel.Visible = "off";
                    app.xcEditField.Visible = "off";
                    app.ycEditFieldLabel.Visible = "off";
                    app.ycEditField.Visible = "off";
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.pEditField.Visible = 'on';
                    app.pEditFieldLabel.Visible = 'on';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                    app.mEditField.Visible='off';
                    app.mEditFieldLabel.Visible='off';
                    app.sEditField.Visible='off';
                    app.sEditFieldLabel.Visible='off';
                case 'Uniform'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "O variabila aleatoare este distribuita " + ...
                        "uniform, daca alegand orice subinterval [c, c + ℓ) " + ...
                        "de lungime ℓ din [a, b), probabilitatea ca variabila " + ...
                        "aleatoare X sa ia valori intr-un astfel de interval este:" + ...
                        "  P(X&#8712;[a,b))=<sup>ℓ</sup>&frasl;<sub>b-a</sub>" + ...
                        "<br><br><br><br>&emsp;&emsp;&emsp;‣ Media:&emsp;&emsp;&emsp;&emsp;  " + ...
                        "M(X) = <sup>a+b</sup>&frasl;<sub>2</sub>" + ...
                        "<br><br>&emsp;&emsp;&emsp;" + ...
                        "‣ Dispersia:&emsp;&emsp;&emsp;&sigma;<sup>2</sup>(X) = " + ...
                        "<sup>(a-b)<sup>2</sup></sup>&frasl;<sub>12</sub>";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "off";
                    app.TipuldefigurDropDownLabel.Visible = "off";
                    app.infoText.FontName = 'Helvetica';
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.xcEditFieldLabel.Visible = "off";
                    app.xcEditField.Visible = "off";
                    app.ycEditFieldLabel.Visible = "off";
                    app.ycEditField.Visible = "off";
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'on';
                    app.bEditField.Visible = 'on';
                    app.bEditFieldLabel.Visible = 'on';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                    app.mEditField.Visible='off';
                    app.mEditFieldLabel.Visible='off';
                    app.sEditField.Visible='off';
                    app.sEditFieldLabel.Visible='off';
                case 'Non-uniform'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "O variabila aleatoare este distribuita " + ...
                        "neuniform, daca probabilitățile <b>NU</b> sunt egale cu 1/n," + ...
                        " adica nu sunt echiprobabile.<br>" + ...
                        "De asemenea, X<sub>0</sub>&#60;X<sub>1</sub>" + ...
                        "&#60;X<sub>2</sub>&#60;...&#60;X<sub>n-1</sub>, iar" + ...
                        " P<sub>0</sub>+P<sub>1</sub>+...+P<sub>n-1</sub> = 1.";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "off";
                    app.TipuldefigurDropDownLabel.Visible = "off";
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.xcEditFieldLabel.Visible = "off";
                    app.xcEditField.Visible = "off";
                    app.ycEditFieldLabel.Visible = "off";
                    app.ycEditField.Visible = "off";
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'on';
                    app.PEditField_1.Visible = 'on';
                    app.PEditField_2.Visible = 'on';
                    app.PEditField_3.Visible = 'on';
                    app.PEditField_4.Visible = 'on';
                    app.PEditField_5.Visible = 'on';
                    app.PEditField_6.Visible = 'on';
                    app.XLabel.Visible = 'on';
                    app.XEditField_1.Visible = 'on';
                    app.XEditField_2.Visible = 'on';
                    app.XEditField_3.Visible = 'on';
                    app.XEditField_4.Visible = 'on';
                    app.XEditField_5.Visible = 'on';
                    app.XEditField_6.Visible = 'on';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                    app.mEditField.Visible='off';
                    app.mEditFieldLabel.Visible='off';
                    app.sEditField.Visible='off';
                    app.sEditFieldLabel.Visible='off';
                case 'Exponential'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "O variabila aleatoare distribuita " + ...
                        "exponential cuantifica timpul pana la producerea primului" + ...
                        " eveniment rar sau intre doua evenimmente rare consecutive." + ...
                        "<br><br>&emsp;&emsp;&emsp;‣ Media:&emsp;&emsp;&emsp;&emsp;  M(X) = &theta;" + ...
                        "<br><br>&emsp;&emsp;&emsp;‣ Dispersia:&emsp;&emsp;&emsp;" + ...
                        "&sigma;<sup>2</sup>(X) = " + ...
                        "&theta;<sup>2</sup>";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "off";
                    app.TipuldefigurDropDownLabel.Visible = "off";
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.xcEditFieldLabel.Visible = "off";
                    app.xcEditField.Visible = "off";
                    app.ycEditFieldLabel.Visible = "off";
                    app.ycEditField.Visible = "off";
                    app.thetaEditField.Visible='on';
                    app.thetaEditFieldLabel.Visible='on';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                    app.mEditField.Visible='off';
                    app.mEditFieldLabel.Visible='off';
                    app.sEditField.Visible='off';
                    app.sEditFieldLabel.Visible='off';
                case 'Pareto'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "Distributia Pareto si distributia " + ...
                        "Zipf se numesc distributiile internetului, pentru " + ...
                        "ca aceste legi de probabilitate guverneaza multe " + ...
                        "caracteristici aleatoare ale retelei internet si a celei WWW." + ...
                        "<br><br>&emsp;&emsp;&emsp;‣ Media:&emsp;&emsp;&emsp;&emsp;  " + ...
                        "M(X) = <sup>&alpha;&beta;</sup>&frasl;<sub>&alpha;-1</sub >" + ...
                        ", &alpha;&#62;1,&beta;&#62;0" + ...
                        "<br><br>&emsp;&emsp;&emsp;‣ Dispersia:&emsp;&emsp;&emsp;" + ...
                        "&sigma;<sup>2</sup>(X)= " + ...
                        "<sup>&alpha;&beta;<sup>2</sup></sup>&frasl;" + ...
                        "<sub>(a-1)<sup>2</sup>(a-2)</sub> , " + ...
                        "&alpha;&#62;2,&beta;&#62;0";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "off";
                    app.TipuldefigurDropDownLabel.Visible = "off";
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.xcEditFieldLabel.Visible = "off";
                    app.xcEditField.Visible = "off";
                    app.ycEditFieldLabel.Visible = "off";
                    app.ycEditField.Visible = "off";
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.alphaEditField.Visible = 'on';
                    app.alphaEditFieldLabel.Visible = 'on';
                    app.betaEditField.Visible = 'on';
                    app.betaEditFieldLabel.Visible = 'on';
                    app.mEditField.Visible='off';
                    app.mEditFieldLabel.Visible='off';
                    app.sEditField.Visible='off';
                    app.sEditFieldLabel.Visible='off';
                case 'Random uniform vector'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "Simularea unui punct (x,y) in " + ...
                         "interiorul unei figuri geometrice.";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "on";
                    app.TipuldefigurDropDownLabel.Visible = "on";
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'on';
                    app.bEditField.Visible = 'on';
                    app.bEditFieldLabel.Visible = 'on';
                    app.cEditField.Visible = 'on';
                    app.cEditFieldLabel.Visible = 'on';
                    app.dEditField.Visible = 'on';
                    app.dEditFieldLabel.Visible = 'on';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                    app.mEditField.Visible='off';
                    app.mEditFieldLabel.Visible='off';
                    app.sEditField.Visible='off';
                    app.sEditFieldLabel.Visible='off';
                case 'Normal'
                    app.ERRORLabel.Visible='off';
                    app.infoText.Text = "Distributia normala este distributia de probabilitate cel mai des folosita ca model " +...
                        "in inteligenta artificiala, machine learning, data mining, analiza si procesarea imaginilor si in statistica." +...
                        "<br>O variabila aleatoare continua X ce are densitatea de probabilitate:" + ...
                        "<br><br><big>f(x) = <sup>1</sup>&frasl;<sub>&#8730;2*&pi;</sub>  *  e<sup><sup></sup>-(x-m)<sup>2</sup>&frasl;" + ...
                        "<sub>2*&sigma;<sup>2</sup></sup></big>";
                    app.infoText.Interpreter = "html";
                    app.mediaEditField.Visible = "off";
                    app.dispersiaEditField.Visible = "off";
                    app.TipuldefigurDropDown.Visible = "off";
                    app.TipuldefigurDropDownLabel.Visible = "off";
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                    app.mEditField.Visible='on';
                    app.mEditFieldLabel.Visible='on';
                    app.sEditField.Visible='on';
                    app.sEditFieldLabel.Visible='on';
            end
        end

        % Value changed function: TipuldefigurDropDown
        function TipuldefigurDropDownValueChanged(app, event)
            value = app.TipuldefigurDropDown.Value;
            switch value
                case "Dreptunghi"
                    app.aEditFieldLabel.Visible = "on";
                    app.aEditField.Visible = "on";
                    app.bEditFieldLabel.Visible = "on";
                    app.bEditField.Visible = "on";
                    app.cEditFieldLabel.Visible = "on";
                    app.cEditField.Visible = "on";
                    app.dEditFieldLabel.Visible = "on";
                    app.dEditField.Visible = "on";
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.xcEditFieldLabel.Visible = "off";
                    app.xcEditField.Visible = "off";
                    app.ycEditFieldLabel.Visible = "off";
                    app.ycEditField.Visible = "off";
                case "Cerc"
                    app.rEditFieldLabel.Visible = "on";
                    app.rEditField.Visible = "on";
                    app.xcEditFieldLabel.Visible = "on";
                    app.xcEditField.Visible = "on";
                    app.ycEditFieldLabel.Visible = "on";
                    app.ycEditField.Visible = "on";
                    app.aEditFieldLabel.Visible = "off";
                    app.aEditField.Visible = "off";
                    app.bEditFieldLabel.Visible = "off";
                    app.bEditField.Visible = "off";
                    app.cEditFieldLabel.Visible = "off";
                    app.cEditField.Visible = "off";
                    app.dEditFieldLabel.Visible = "off";
                    app.dEditField.Visible = "off";
                case "Elipsa"
                    app.aEditFieldLabel.Visible = "on";
                    app.aEditField.Visible = "on";
                    app.bEditFieldLabel.Visible = "on";
                    app.bEditField.Visible = "on";
                    app.xcEditFieldLabel.Visible = "on";
                    app.xcEditField.Visible = "on";
                    app.ycEditFieldLabel.Visible = "on";
                    app.ycEditField.Visible = "on";
                    app.rEditFieldLabel.Visible = "off";
                    app.rEditField.Visible = "off";
                    app.cEditFieldLabel.Visible = "off";
                    app.cEditField.Visible = "off";
                    app.dEditFieldLabel.Visible = "off";
                    app.dEditField.Visible = "off";
                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {516, 516, 516};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {516, 516};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x', 465};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DistribuiileListBoxLabel = uilabel(app.LeftPanel);
            app.DistribuiileListBoxLabel.Text = 'Distribuțiile';
            app.DistribuiileListBox = uilistbox(app.LeftPanel);
            app.DistribuiileListBox.Items = {'Bernoulli', 'Geometric', 'Binomial', 'Uniform', 'Non-uniform', 'Exponential', 'Pareto', 'Random uniform vector', 'Normal'};
            app.DistribuiileListBox.ValueChangedFcn = createCallbackFcn(app, @DistribuiileListBoxValueChanged, true);
            app.DistribuiileListBox.Value = 'Bernoulli';
            app.nEditFieldLabel = uilabel(app.LeftPanel);
            app.nEditFieldLabel.Visible = 'off';
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.LeftPanel, 'numeric');
            app.nEditField.Visible = 'off';
            app.pEditFieldLabel = uilabel(app.LeftPanel);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pEditField.Limits = [0 Inf];
            app.NumruldevalorigenerateEditFieldLabel = uilabel(app.LeftPanel);
            app.NumruldevalorigenerateEditFieldLabel.Text = 'Numărul de valori generate';
            app.NumruldevalorigenerateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumruldevalorigenerateEditField.Limits = [1 10000000];
            app.NumruldevalorigenerateEditField.RoundFractionalValues = 'on';
            app.NumruldevalorigenerateEditField.Value = 1;
            app.EnterButton = uibutton(app.LeftPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.thetaEditFieldLabel = uilabel(app.LeftPanel);
            app.thetaEditFieldLabel.Visible = 'off';
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.thetaEditField.Limits = [0 Inf];
            app.thetaEditField.Visible = 'off';
            app.alphaEditFieldLabel = uilabel(app.LeftPanel);
            app.alphaEditFieldLabel.Visible = 'off';
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.alphaEditField.Visible = 'off';
            app.betaEditFieldLabel = uilabel(app.LeftPanel);
            app.betaEditFieldLabel.Visible = 'off';
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.betaEditField.Visible = 'off';
            app.ERRORLabel = uilabel(app.LeftPanel);
            app.ERRORLabel.Visible = 'off';
            app.ERRORLabel.Text = 'ERROR: ';
            app.PLabel = uilabel(app.LeftPanel);
            app.PLabel.Visible = 'off';
            app.PLabel.Text = 'P:';
            app.PEditField_1 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_1.Limits = [0 1];
            app.PEditField_1.Visible = 'off';
            app.PEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_2.Limits = [0 1];
            app.PEditField_2.Visible = 'off';
            app.PEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_3.Limits = [0 1];
            app.PEditField_3.Visible = 'off';
            app.PEditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_4.Limits = [0 1];
            app.PEditField_4.Visible = 'off';
            app.PEditField_5 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_5.Limits = [0 1];
            app.PEditField_5.Visible = 'off';
            app.PEditField_6 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_6.Limits = [0 1];
            app.PEditField_6.Visible = 'off';
            app.XLabel = uilabel(app.LeftPanel);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X:';
            app.XEditField_1 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_1.Visible = 'off';
            app.XEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_2.Visible = 'off';
            app.XEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_3.Visible = 'off';
            app.XEditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_4.Visible = 'off';
            app.XEditField_5 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_5.Visible = 'off';
            app.XEditField_6 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_6.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Visible = 'off';
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.Visible = 'off';
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.Visible = 'off';
            app.cEditFieldLabel = uilabel(app.LeftPanel);
            app.cEditFieldLabel.Visible = 'off';
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.LeftPanel, 'numeric');
            app.cEditField.Visible = 'off';
            app.dEditFieldLabel = uilabel(app.LeftPanel);
            app.dEditFieldLabel.Visible = 'off';
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dEditField.RoundFractionalValues = 'on';
            app.dEditField.Visible = 'off';
            app.TipuldefigurDropDownLabel = uilabel(app.LeftPanel);
            app.TipuldefigurDropDownLabel.Visible = 'off';
            app.TipuldefigurDropDownLabel.Text = 'Tipul de figură';
            app.TipuldefigurDropDown = uidropdown(app.LeftPanel);
            app.TipuldefigurDropDown.Items = {'Dreptunghi', 'Cerc', 'Elipsa'};
            app.TipuldefigurDropDown.ValueChangedFcn = createCallbackFcn(app, @TipuldefigurDropDownValueChanged, true);
            app.TipuldefigurDropDown.Visible = 'off';
            app.TipuldefigurDropDown.Value = 'Dreptunghi';
            app.rEditFieldLabel = uilabel(app.LeftPanel);
            app.rEditFieldLabel.Visible = 'off';
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.rEditField.Limits = [0.001 Inf];
            app.rEditField.Visible = 'off';
            app.rEditField.Value = 0.001;
            app.xcEditFieldLabel = uilabel(app.LeftPanel);
            app.xcEditFieldLabel.Visible = 'off';
            app.xcEditFieldLabel.Text = 'xc';
            app.xcEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xcEditField.Visible = 'off';
            app.ycEditFieldLabel = uilabel(app.LeftPanel);
            app.ycEditFieldLabel.Visible = 'off';
            app.ycEditFieldLabel.Text = 'yc';
            app.ycEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ycEditField.Visible = 'off';
            app.mEditFieldLabel = uilabel(app.LeftPanel);
            app.mEditFieldLabel.Visible = 'off';
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mEditField.Visible = 'off';
            app.sEditFieldLabel = uilabel(app.LeftPanel);
            app.sEditFieldLabel.Interpreter = 'html';
            app.sEditFieldLabel.Visible = 'off';
            app.sEditFieldLabel.Text = 'σ<sup>2</sup>';
            app.sEditField = uieditfield(app.LeftPanel, 'numeric');
            app.sEditField.Limits = [0.0001 Inf];
            app.sEditField.Visible = 'off';
            app.sEditField.Value = 0.0001;
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Histograma')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.INFOLabel = uilabel(app.CenterPanel);
            app.INFOLabel.Text = 'INFO';
            app.PLOTLabel = uilabel(app.CenterPanel);
            app.PLOTLabel.Text = 'PLOT';
            app.infoText = uilabel(app.CenterPanel);
            app.infoText.Interpreter = 'html';
            app.infoText.Text = {'<div ><div>O variabila aleatoare discreta Bernoulli cuantifica rezultatul unui experiment aleator (Bernoulli) format dintr-un număr cunoscut sau necunoscut de incercari independente, fiecare avand doar doua posibilitati: </div><div style=''margin: 1000px 0 0 0;''><ul style=''margin-top: 10px''><li>Succes - 1</li><li>Esec - 0</li></ul></div></div>'; '<br>&emsp;&emsp;&emsp;‣ Media:&emsp;&emsp;&emsp;&emsp;  M(X) = p<br><br>&emsp;&emsp;&emsp;‣ Dispersia:&emsp;&emsp;&emsp;&sigma;<sup>2</sup>(X) = p * q'};
            app.dispersiaEditField = uieditfield(app.CenterPanel, 'numeric');
            app.dispersiaEditField.Editable = 'off';
            app.dispersiaEditField.Visible = 'off';
            app.mediaEditField = uieditfield(app.CenterPanel, 'numeric');
            app.mediaEditField.Editable = 'off';
            app.mediaEditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Densitate de probabilitate')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Functia de repartitie')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end